classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImportLabel             matlab.ui.control.Label
        Skewness                matlab.ui.control.NumericEditField
        SkewnessLabel           matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        EditField_5             matlab.ui.control.NumericEditField
        EditField_4             matlab.ui.control.NumericEditField
        EditField_3             matlab.ui.control.NumericEditField
        EditField_2             matlab.ui.control.NumericEditField
        EditField               matlab.ui.control.NumericEditField
        FifthEditField          matlab.ui.control.EditField
        FifthEditFieldLabel     matlab.ui.control.Label
        FourthEditField         matlab.ui.control.EditField
        FourthEditFieldLabel    matlab.ui.control.Label
        ThirdEditField          matlab.ui.control.EditField
        ThirdEditFieldLabel     matlab.ui.control.Label
        SecondEditField         matlab.ui.control.EditField
        SecondEditFieldLabel    matlab.ui.control.Label
        FirstEditField          matlab.ui.control.EditField
        FirstEditFieldLabel     matlab.ui.control.Label
        KurtosisEditField       matlab.ui.control.NumericEditField
        KurtosisEditFieldLabel  matlab.ui.control.Label
        VarianceEditField       matlab.ui.control.NumericEditField
        VarianceEditFieldLabel  matlab.ui.control.Label
        MeanEditField           matlab.ui.control.NumericEditField
        MeanEditFieldLabel      matlab.ui.control.Label
        Button                  matlab.ui.control.StateButton
        pmf                     matlab.ui.control.UIAxes
        cdf                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;


            file=uigetfile;
            fid = fopen(file,'r');
            txt = lower(fscanf(fid,'%s'));
            character = 'a':'z';
            for n=1:26
                freq(n)=length(strfind(txt,character(n)));
            end
            c = 1:26;
            N = 26;
            charP = freq/sum(freq);
            mea = sum(c.*charP);
            variance =sum((c-mea).^2.*charP);
            sd = sqrt(variance);     %standard deviation
            skew = sum((c-mea).^3)/(N*sd^3);
            kurt = sum((c-mea).^4)/(N*sd^4);
            
            for i = 1:26
                cdf(i) =sum(charP(1:i));
            end

            app.KurtosisEditField.Value=kurt;
            app.MeanEditField.Value=mea;
            app.Skewness.Value=skew;
            app.VarianceEditField.Value=variance;

            [max,index] = maxk(freq,5);

            app.EditField.Value=max(1);
            app.EditField_2.Value=max(2);
            app.EditField_3.Value=max(3);
            app.EditField_4.Value=max(4);
            app.EditField_5.Value=max(5);

            app.FirstEditField.Value=upper(character(index(1)));
            app.SecondEditField.Value=upper(character(index(2)));
            app.ThirdEditField.Value=upper(character(index(3)));
            app.FourthEditField.Value=upper(character(index(4)));
            app.FifthEditField.Value=upper(character(index(5)));

            x = {'a ','b ','c ','d ','e ','f ','g ','h ','i ','j ','k ','l ','m ','n ','o ','p ','q ','r ','s ','t ','u ','v ','w ','x ','y ','z'};
            X = categorical(x);
            X = reordercats(X,x);
            bar(app.pmf,X,charP)

            x = 0:1:26;
            cdf = [0 cdf];
            stairs(app.cdf,x,cdf)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cdf = uiaxes(app.UIFigure);
            title(app.cdf, {'            '; ''; '     CDF'})
            xlabel(app.cdf, {'x'; ''})
            ylabel(app.cdf, {'F(x)'; ''})
            zlabel(app.cdf, 'Z')
            app.pmf = uiaxes(app.UIFigure);
            title(app.pmf, 'PMF')
            xlabel(app.pmf, {'x'; ''})
            ylabel(app.pmf, {'f(x)'; ''})
            zlabel(app.pmf, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'import.png';
            app.Button.Text = '';
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarianceEditFieldLabel = uilabel(app.UIFigure);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.KurtosisEditFieldLabel = uilabel(app.UIFigure);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstEditFieldLabel = uilabel(app.UIFigure);
            app.FirstEditFieldLabel.Text = 'First';
            app.FirstEditField = uieditfield(app.UIFigure, 'text');
            app.SecondEditFieldLabel = uilabel(app.UIFigure);
            app.SecondEditFieldLabel.Text = 'Second';
            app.SecondEditField = uieditfield(app.UIFigure, 'text');
            app.ThirdEditFieldLabel = uilabel(app.UIFigure);
            app.ThirdEditFieldLabel.Text = 'Third';
            app.ThirdEditField = uieditfield(app.UIFigure, 'text');
            app.FourthEditFieldLabel = uilabel(app.UIFigure);
            app.FourthEditFieldLabel.Text = 'Fourth';
            app.FourthEditField = uieditfield(app.UIFigure, 'text');
            app.FifthEditFieldLabel = uilabel(app.UIFigure);
            app.FifthEditFieldLabel.Text = 'Fifth';
            app.FifthEditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Most repeted Characters with therir frequencies:'};
            app.SkewnessLabel = uilabel(app.UIFigure);
            app.SkewnessLabel.Text = 'Skewness';
            app.Skewness = uieditfield(app.UIFigure, 'numeric');
            app.ImportLabel = uilabel(app.UIFigure);
            app.ImportLabel.Text = 'Import';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end