classdef P2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        OperationDropDown       matlab.ui.control.DropDown
        OperationDropDownLabel  matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
        ResultEditField         matlab.ui.control.NumericEditField
        ResultEditFieldLabel    matlab.ui.control.Label
        Input2EditField         matlab.ui.control.NumericEditField
        Input2EditFieldLabel    matlab.ui.control.Label
        Input1EditField         matlab.ui.control.NumericEditField
        Input1EditFieldLabel    matlab.ui.control.Label
        AnalysisofStochasticProcessesLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        X % Description
        t % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path] = uigetfile('.mat','Select input file!');
            load(file)
            app.X=X;
            app.t=t;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            [r, c] = size(app.X);

            switch app.OperationDropDown.Value

                case "Plot 'M' Sample Functions"

                    M = app.Input1EditField.Value;
                    figure("Name","Sample functions");
                    for i = 1:M
                        if (mod(M,2) ~= 0)
                            if (i == M)
                                subplot(ceil(M/2), 2, [i i+1]);
                                plot(app.t, app.X(i,:));
                                xlabel('time(sec)');
                                ylabel(['X', num2str(i),'(t)']);
                                title(['Sample function ', num2str(i), ' of the ensemble']);
                                grid;
                            else
                                subplot(ceil(M/2), 2, i);
                                plot(app.t, app.X(i,:));
                                xlabel('time(sec)');
                                ylabel(['X', num2str(i),'(t)']);
                                title(['Sample function ', num2str(i), ' of the ensemble']);
                                grid;
                            end

                        else
                            subplot(M/2, 2, i);
                            plot(app.t, app.X(i,:));
                            xlabel('time(sec)');
                            ylabel(['X', num2str(i),'(t)']);
                            title(['Sample function ', num2str(i), ' of the ensemble']);
                            grid;
                        end
                    end


                case "Ensemble mean"
                    ensemble_mean = mean(app.X);
                    figure("Name","Ensemble Mean");
                    plot(app.t, ensemble_mean);
                    xlabel('time (sec)');
                    ylabel('E[x(t)]');
                    title('Ensemble Mean of the random process');
                    grid;


                case "Time Mean of 'nth' Sample Function"

                    n = app.Input1EditField.Value;
                    taw = app.t(2)- app.t(1);
                    time_mean = ( 1/(app.t(end) - app.t(1)) )*( sum(app.X(n,:)) * taw );
                    app.ResultEditField.Value = time_mean;


                case "ACF between 'i' and 'j' samples"

                    i = app.Input1EditField.Value;
                    j = app.Input2EditField.Value;

                    ACF_ij = mean( app.X(:,i) .* app.X(:,j) );
                    app.ResultEditField.Value = ACF_ij;

                case "ACF between every i and j Samples"

                    time_ACF = app.X' * app.X;
                    time_ACF = time_ACF./c;
                    figure('Name','ACF between every i and j Samples');
                    a = surf(time_ACF);
                    set(a, 'LineStyle','none');
                    title("ACF between every i and j Samples in the Ensemble");
                    xlabel('i Sample');
                    ylabel('j Sample');
                    zlabel('Amplitude');


                case "Time ACF of the 'nth' Sample Function"
                    N = app.Input1EditField.Value;
                    ACF_N = zeros(1, c);
                    dt = app.t(2) - app.t(1);
                    for i = 0:c-1
                        ACF_N(i+1) = 0;
                        for j = 1:c
                            if (i+j <= c)
                                ACF_N(i+1) = ACF_N(i+1) + (( app.X(N, j) * app.X(N, j+i)));
                            end
                        end
                        ACF_N(i+1) = (1/(app.t(end) - app.t(1)))*dt*ACF_N(i+1);
                    end

                    taw = (linspace(-c, c, 2*c)).* (dt);
                    neg_side_tACF = fliplr(ACF_N);
                    ACF_N = [neg_side_tACF  ACF_N];

                    figure("Name",'Time ACF');
                    plot(taw,  ACF_N);
                    xlabel('taw (sec)');
                    ylabel(['R_x(x_', num2str(N), '(t))']);
                    xlim([taw(1) taw(end)]);
                    title(['Time ACF of ' , num2str(N),'th sample function']);
                    grid;

                case "Power Spectral Density"
                    PSD = zeros(1, c);
                    for i = 1:r
                        temp = fftshift(fft( app.X(i,:)));
                        temp = abs(temp/c).^2;
                        PSD =  PSD + temp;
                    end
                    f = linspace(-50, 50, c);
                    PSD =  PSD / r;
                    figure("Name","Power Spectral Density");
                    plot(f,  PSD);
                    xlabel('Frequency (Hz)');
                    ylabel('S_x(w)');
                    title('Power Spectral Density of the Random Process');
                    grid;
                    xlim([f(1) f(end)]);

                case "Total Average Power"

                    En_ACF = zeros(1, c);
                    %Ensemble ACF
                    for i = 0:c-1
                        temp = mean(( app.X(:, [1: c-i]).*  app.X(:, [i+1:c]))');
                        En_ACF(i+1)=  mean(temp');
                    end
                    app.ResultEditField.Value = En_ACF(1);


                case "Ensemble ACF"
                    
                    En_ACF = zeros(1, c);
                    %Ensemble ACF
                    for i = 0:c-1
                        temp = mean(( app.X(:, [1: c-i]).*  app.X(:, [i+1:c]))');
                        En_ACF(i+1)=  mean(temp');
                    end
                    negside = fliplr( En_ACF);
                    Double_ACF = [negside  En_ACF];
                    taw = (linspace(-c, c, 2*c)).* ( app.t(2) -  app.t(1));
    
                    figure("Name","Ensemble ACF");
                    plot(taw,  Double_ACF);
                    xlabel('taw(sec)');
                    ylabel(['Ensemble ACF']);
                    xlim([taw(1) taw(end)]);
                    title('Ensemble ACF');
                    grid;


            end


        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;

            switch app.OperationDropDown.Value

                case "Plot 'M' Sample Functions"
                    app.Input1EditField.Visible="on";
                    app.Input1EditFieldLabel.Visible="on";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.Input1EditFieldLabel.Text="m";
                    app.ResultEditField.Visible="off";
                    app.ResultEditFieldLabel.Visible="off";

                case "Ensemble mean"
                    app.Input1EditField.Visible="off";
                    app.Input1EditFieldLabel.Visible="off";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.ResultEditField.Visible="off";
                    app.ResultEditFieldLabel.Visible="off";
                    app.ResultEditFieldLabel.Text="Ensemble mean";
                    
                case "Ensemble ACF"
                    app.Input1EditField.Visible="off";
                    app.Input1EditFieldLabel.Visible="off";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.ResultEditField.Visible="off";
                    app.ResultEditFieldLabel.Visible="off";
                    app.ResultEditFieldLabel.Text="Ensemble mean";

                case "Time Mean of 'nth' Sample Function"
                    app.Input1EditField.Visible="on";
                    app.Input1EditFieldLabel.Visible="on";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.Input1EditFieldLabel.Text="n";
                    app.ResultEditField.Visible="on";
                    app.ResultEditFieldLabel.Visible="on";
                    app.ResultEditFieldLabel.Text="Time mean";

                case "ACF between 'i' and 'j' samples"
                    app.Input1EditField.Visible="on";
                    app.Input1EditFieldLabel.Visible="on";
                    app.Input2EditField.Visible="on";
                    app.Input2EditFieldLabel.Visible="on";
                    app.Input1EditFieldLabel.Text="i";
                    app.Input2EditFieldLabel.Text="j";
                    app.ResultEditField.Visible="on";
                    app.ResultEditFieldLabel.Visible="on";
                    app.ResultEditFieldLabel.Text="ACF";

                case "ACF between every i and j Samples"
                    app.Input1EditField.Visible="off";
                    app.Input1EditFieldLabel.Visible="off";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.ResultEditField.Visible="off";
                    app.ResultEditFieldLabel.Visible="off";

                case "Time ACF of the 'nth' Sample Function"
                    app.Input1EditField.Visible="on";
                    app.Input1EditFieldLabel.Visible="on";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.Input1EditFieldLabel.Text="n";
                    app.ResultEditField.Visible="off";
                    app.ResultEditFieldLabel.Visible="off";
                    app.ResultEditFieldLabel.Text="time ACF";

                case "Power Spectral Density"
                    app.Input1EditField.Visible="off";
                    app.Input1EditFieldLabel.Visible="off";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.ResultEditField.Visible="off";
                    app.ResultEditFieldLabel.Visible="off";
                    app.ResultEditFieldLabel.Text="PSD";

                case "Total Average Power"
                    app.Input1EditField.Visible="off";
                    app.Input1EditFieldLabel.Visible="off";
                    app.Input2EditField.Visible="off";
                    app.Input2EditFieldLabel.Visible="off";
                    app.ResultEditField.Visible="on";
                    app.ResultEditFieldLabel.Visible="on";
                    app.ResultEditFieldLabel.Text="Avg. Power";

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisofStochasticProcessesLabel = uilabel(app.UIFigure);
            app.AnalysisofStochasticProcessesLabel.Text = {'Analysis of Stochastic Processes'; ''};
            app.Input1EditFieldLabel = uilabel(app.UIFigure);
            app.Input1EditFieldLabel.Text = 'Input 1';
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input2EditFieldLabel = uilabel(app.UIFigure);
            app.Input2EditFieldLabel.Text = 'Input 2';
            app.Input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'Plot ''M'' Sample Functions', 'Ensemble mean', 'Ensemble ACF', 'Time Mean of ''nth'' Sample Function', 'ACF between ''i'' and ''j'' samples', 'ACF between every i and j Samples', 'Time ACF of the ''nth'' Sample Function', 'Power Spectral Density', 'Total Average Power'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = 'Plot ''M'' Sample Functions';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'import.png';
            app.Button.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end